classdef GUI_BraTS_CEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BraTSCESTUIFigure             matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        startTab                      matlab.ui.container.Tab
        fastMRI                       matlab.ui.control.Label
        fastMRI_web                   matlab.ui.control.Label
        fastMRI_button                matlab.ui.control.Button
        BraTS                         matlab.ui.control.Label
        BraTS_web                     matlab.ui.control.Label
        BraTS_button                  matlab.ui.control.Button
        BeforestartuppleasedownloadthefollowingdatasetsLabel  matlab.ui.control.Label
        author_name                   matlab.ui.control.Label
        welcome                       matlab.ui.control.Label
        welcome_1                     matlab.ui.control.Label
        TipsclickonthetoptabstoswitchdifferentstepsLabel  matlab.ui.control.Label
        step1_page                    matlab.ui.container.Tab
        step1_waitbar                 matlab.ui.control.UIAxes
        PreprocessoffastMRIdatasettomatfileLabel  matlab.ui.control.Label
        fastMRIfolderTextAreaLabel    matlab.ui.control.Label
        fastMRIfolderTextArea         matlab.ui.control.TextArea
        h5openButton                  matlab.ui.control.Button
        matopenButton                 matlab.ui.control.Button
        matsavefolderLabel            matlab.ui.control.Label
        matdatafolderTextArea         matlab.ui.control.TextArea
        TipsrawdatathatdoesnotmeettheminimumconditionwillbeskippedLabel  matlab.ui.control.Label
        numofslicesSpinnerLabel       matlab.ui.control.Label
        numofslicesSpinner            matlab.ui.control.Spinner
        numofcoilsSpinnerLabel        matlab.ui.control.Label
        numofcoilsSpinner             matlab.ui.control.Spinner
        PresettingsLabel              matlab.ui.control.Label
        Step1RunButton                matlab.ui.control.Button
        clickRuntostartLabel          matlab.ui.control.Label
        imagesizeSpinnerLabel         matlab.ui.control.Label
        imagesizeSpinner              matlab.ui.control.Spinner
        datanumSpinnerLabel           matlab.ui.control.Label
        datanumSpinner                matlab.ui.control.Spinner
        step2_page                    matlab.ui.container.Tab
        step2_waitbar                 matlab.ui.control.UIAxes
        TransferBraTSfromrealtocomplexLabel  matlab.ui.control.Label
        niiopenButton                 matlab.ui.control.Button
        matopenButton_2               matlab.ui.control.Button
        TipsrawdatathatarerecordedasdefectivewillbeskippedLabel  matlab.ui.control.Label
        PresettingsLabel_2            matlab.ui.control.Label
        Step2RunButton                matlab.ui.control.Button
        clickRuntostartLabel_2        matlab.ui.control.Label
        BraTSfolderTextAreaLabel      matlab.ui.control.Label
        BraTSfolderTextArea           matlab.ui.control.TextArea
        complexfolderLabel            matlab.ui.control.Label
        matdatafolderTextArea_2       matlab.ui.control.TextArea
        sliceintervalSpinnerLabel     matlab.ui.control.Label
        sliceintervalSpinner          matlab.ui.control.Spinner
        brainvoxelsizeSpinnerLabel    matlab.ui.control.Label
        brainvoxelsizeSpinner         matlab.ui.control.Spinner
        lesionvoxelsizeSpinnerLabel   matlab.ui.control.Label
        lesionvoxelsizeSpinner        matlab.ui.control.Spinner
        patientnumSpinnerLabel        matlab.ui.control.Label
        patientnumSpinner             matlab.ui.control.Spinner
        Step3Tab                      matlab.ui.container.Tab
        step3_waitbar                 matlab.ui.control.UIAxes
        regionofCSMButtonGroup        matlab.ui.container.ButtonGroup
        fullFOVButton                 matlab.ui.control.RadioButton
        partialFOVButton              matlab.ui.control.RadioButton
        AddCESTcontrastformulticoilBraTSCESTdataLabel  matlab.ui.control.Label
        CESTopenButton                matlab.ui.control.Button
        PresettingsLabel_3            matlab.ui.control.Label
        CESTsavefolderLabel           matlab.ui.control.Label
        CESTsavefolderTextArea        matlab.ui.control.TextArea
        dataenhanceSwitchLabel        matlab.ui.control.Label
        dataenhanceSwitch             matlab.ui.control.Switch
        TSET2blurSwitchLabel          matlab.ui.control.Label
        TSET2blurSwitch               matlab.ui.control.Switch
        patientnumSpinner_2Label      matlab.ui.control.Label
        patientnumSpinner_2           matlab.ui.control.Spinner
        imagesizeSpinner_2Label       matlab.ui.control.Label
        imagesizeSpinner_2            matlab.ui.control.Spinner
        TipschoosefullFOVofCSMforbetterperformanceontrainingLabel  matlab.ui.control.Label
        Step3RunButton                matlab.ui.control.Button
        clickRuntostartLabel_3        matlab.ui.control.Label
        ViewTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_6                      matlab.ui.control.UIAxes
        ViewtheBraTSCESTresultsLabel  matlab.ui.control.Label
        readoutmodalityButtonGroup    matlab.ui.container.ButtonGroup
        T1Button                      matlab.ui.control.RadioButton
        T2Button                      matlab.ui.control.RadioButton
        FLAIRButton                   matlab.ui.control.RadioButton
        patientidSpinnerLabel         matlab.ui.control.Label
        patientidSpinner              matlab.ui.control.Spinner
        ViewRunButton                 matlab.ui.control.Button
        S0Label                       matlab.ui.control.Label
        divisionLabel                 matlab.ui.control.Label
        ppmLabel                      matlab.ui.control.Label
        APTwLabel                     matlab.ui.control.Label
        ppmLabel_2                    matlab.ui.control.Label
        CSMLabel                      matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function start_up_init(app)
            addpath function
            banner_img = imread('banner.jpg');
            banner_ax = uiaxes(app.startTab,'Position',[10 250 560 150]);
            image(banner_img,'Parent',banner_ax); 
            set(banner_ax,'visible','off');
            axis(banner_ax, 'tight')
            fig_size = 120;
            blank_img = ones([fig_size,fig_size,3])*255;
            imshow(blank_img, 'Parent', app.UIAxes)
            set(app.UIAxes,'visible','off');
            axis(app.UIAxes, 'tight')
            imshow(blank_img, 'Parent', app.UIAxes_2)
            set(app.UIAxes_2,'visible','off');
            axis(app.UIAxes_2, 'tight')
            imshow(blank_img, 'Parent', app.UIAxes_3)
            set(app.UIAxes_3,'visible','off');
            axis(app.UIAxes_3, 'tight')
            imshow(blank_img, 'Parent', app.UIAxes_4)
            set(app.UIAxes_4,'visible','off');
            axis(app.UIAxes_4, 'tight')
            imshow(blank_img, 'Parent', app.UIAxes_5)
            set(app.UIAxes_5,'visible','off');
            axis(app.UIAxes_5, 'tight')
            imshow(blank_img, 'Parent', app.UIAxes_6)
            set(app.UIAxes_6,'visible','off');
            axis(app.UIAxes_6, 'tight')
            %% step1
            global fastmri_datanum h5_file_path mat_file_path fastmri_fullcsm fastmri_size fastmri_slice fastmri_coil
            h5_file_path = char(app.fastMRIfolderTextArea.Value);
            mat_file_path = char(app.matdatafolderTextArea.Value);
            fastmri_datanum = 10;
            fastmri_fullcsm = "full";
            fastmri_size = 96;
            fastmri_slice = 10;
            fastmri_coil = 16;
            hold(app.step1_waitbar, 'on')
            plot(app.step1_waitbar,[0,1],[0,0],'-','LineWidth',15,'Color',[0.9,0.9,0.9]);
            hold(app.step1_waitbar,'off')
            set(app.step1_waitbar,'XLim',[0,1],'xtick',[],'ytick',[],'Visible','off');
            %% step2
            global nii_file_path cpx_file_path cpx_patient_num cpx_lesion_size cpx_brain_size cpx_slice_inter
            nii_file_path = char(app.BraTSfolderTextArea.Value);
            cpx_file_path = char(app.matdatafolderTextArea_2.Value);
            cpx_patient_num = 50;
            cpx_lesion_size = 144;
            cpx_brain_size = 1e4;
            cpx_slice_inter = 5;
            hold(app.step2_waitbar, 'on')
            plot(app.step2_waitbar,[0,1],[0,0],'-','LineWidth',15,'Color',[0.9,0.9,0.9]);
            hold(app.step2_waitbar,'off')
            set(app.step2_waitbar,'XLim',[0,1],'xtick',[],'ytick',[],'Visible','off');
            %% step4
            global CEST_file_path CEST_patient_num CEST_size CEST_enhance CEST_blur CEST_fullcsm
            CEST_file_path = char(app.CESTsavefolderTextArea.Value);
            CEST_patient_num = 50;
            CEST_size = 96;
            CEST_enhance = 1;
            CEST_blur = 1;
            CEST_fullcsm = "full";
            hold(app.step3_waitbar, 'on')
            plot(app.step3_waitbar,[0,1],[0,0],'-','LineWidth',15,'Color',[0.9,0.9,0.9]);
            hold(app.step3_waitbar,'off')
            set(app.step3_waitbar,'XLim',[0,1],'xtick',[],'ytick',[],'Visible','off');
            %% view
            global view_mod view_patient_num
            view_mod = "T1";
            view_patient_num = 1;
        end

        % Button pushed function: fastMRI_button
        function fastMRI_buttonPushed(app, event)
            url = 'https://fastmri.med.nyu.edu/';
            web(url,'-browser')
        end

        % Button pushed function: BraTS_button
        function BraTS_buttonButtonPushed(app, event)
            url = 'http://braintumorsegmentation.org/';
            web(url,'-browser')
        end

        % Button pushed function: h5openButton
        function h5openButtonPushed(app, event)
            global h5_file_path
            h5_file_path = uigetdir('..');
            app.fastMRIfolderTextArea.Value = h5_file_path;
        end

        % Value changed function: fastMRIfolderTextArea
        function fastMRIfolderTextAreaValueChanged(app, event)
            global h5_file_path
            h5_file_path = app.fastMRIfolderTextArea.Value;
        end

        % Button pushed function: matopenButton
        function matopenButtonPushed(app, event)
            global mat_file_path
            mat_file_path = uigetdir('Data');
            app.matdatafolderTextArea.Value = mat_file_path;
        end

        % Value changed function: matdatafolderTextArea
        function matdatafolderTextAreaValueChanged(app, event)
            global mat_file_path
            mat_file_path = app.matdatafolderTextArea.Value;
        end

        % Button pushed function: Step1RunButton
        function Step1RunButtonPushed(app, event)
            clc
            %% prepare wait bar
            hold(app.step1_waitbar, 'on')
            plot(app.step1_waitbar,[0,1],[0,0],'-','LineWidth',15,'Color',[0.9,0.9,0.9]);
            hold(app.step1_waitbar,'off')
            drawnow
            global fastmri_datanum h5_file_path mat_file_path fastmri_fullcsm fastmri_size fastmri_slice fastmri_coil
            %% preset params
            h5_folder = char(h5_file_path); % enter fastMRI h5 file folder dir
            % you can download fastMRI data at https://fastmri.med.nyu.edu/
            h5_folder_list = dir(fullfile(h5_folder,'*.h5'));
            full_csm = fastmri_fullcsm; % choose "full" or "partial" for region of csm
            mat_folder = char(mat_file_path); % enter fastMRI mat file folder save dir
            total_data_num = length(h5_folder_list);
            data_num = fastmri_datanum; % set the number of data to be converted
            data_num = min(data_num, total_data_num);
            fprintf('load .h5 file from %s that has %s datas\n', [h5_folder, string(total_data_num)])
            img_size = [fastmri_size, fastmri_size]; % resized width & hight
            slice_num = fastmri_slice; % num of slices extracted from rawdata
            coil_num = fastmri_coil; % num of coils extracted from rawdata
            %% prepare wait bar
            tstart = tic;
            text = "prcessing data: 0/"+num2str(data_num)+" est. time: 00:00";
            app.clickRuntostartLabel.Text = text;
            drawnow
            %% read each data and convert to .mat
            for d=1:data_num
                data_dir = fullfile(h5_folder_list(d).folder, h5_folder_list(d).name);
                ksp = h5read(data_dir,'/kspace');
                ksp = ksp.r + 1i*ksp.i;
                if size(ksp,3)<coil_num || size(ksp,4)<slice_num
                    fprintf('{> data %s does not have enough coil or slice\n', string(d));
                    continue
                else
                    fprintf('{> data %s processing\n   ', string(d));
                end
                %% resize img to preset sizes and calculate csm
                image = fft2c_mri(ksp);
                x_num = size(image,1);
                y_num = size(image,2);
                image = image(:, y_num*0.5-x_num*0.5+1:y_num*0.5+x_num*0.5, :, :); % skip sampling on ky-axis
                for s=1:slice_num
                    fprintf('slice %s => ', string(s));
                    image_slice = squeeze(image(:,:,:,s));
                    img_coil_resize = zeros(fastmri_size, fastmri_size, coil_num);
                    for c=1:coil_num
                        img_coil = image_slice(:,:,c);
                        img_coil = imresize(img_coil, img_size);
                        img_coil = rot90(img_coil);
                        img_coil_resize(:,:,c) = img_coil;
                    end
                    [sensitivities, reference] = cal_csm(img_coil_resize, full_csm);
                    save_path = fullfile(mat_folder, num2str(d,"%04d"));
                    if ~exist(save_path,'dir')
                        mkdir(save_path);
                    end
                    save(fullfile(save_path, "espirit"+num2str(s,"%02d")+".mat"), "sensitivities", "reference");
                end
                fprintf('done\n')
                %% plot waitbar
                hold(app.step1_waitbar, 'on')
                plot(app.step1_waitbar,[0,d/data_num],[0,0],'-','LineWidth',15,'Color',[0.1,0.9,0.1]);            
                hold(app.step1_waitbar,'off')
                tnow = toc(tstart);
                term = tnow/d*(data_num-d);
                term_min = floor(term/60);
                term_sec = floor(mod(term,60));
                text = "prcessing data: "+num2str(d)+"/"+num2str(data_num)+" est. time: "+num2str(term_min,'%02d')+":"+num2str(term_sec,'%02d');
                app.clickRuntostartLabel.Text = text;
                drawnow
            end
        end

        % Selection changed function: regionofCSMButtonGroup
        function regionofCSMButtonGroupSelectionChanged(app, event)
            global CEST_fullcsm
            selectedButton = app.regionofCSMButtonGroup.SelectedObject;
            if selectedButton.Text == "full FOV"
                CEST_fullcsm = "full";
            else
                CEST_fullcsm = "partial";
            end
        end

        % Value changed function: numofslicesSpinner
        function numofslicesSpinnerValueChanged(app, event)
            global fastmri_slice
            fastmri_slice =  app.numofslicesSpinner.Value;
        end

        % Value changed function: numofcoilsSpinner
        function numofcoilsSpinnerValueChanged(app, event)
            global fastmri_coil
            fastmri_coil = app.numofcoilsSpinner.Value;
        end

        % Button pushed function: niiopenButton
        function niiopenButtonPushed(app, event)
            global nii_file_path
            nii_file_path = uigetdir('..');
            app.BraTSfolderTextArea.Value = nii_file_path;
        end

        % Value changed function: BraTSfolderTextArea
        function BraTSfolderTextAreaValueChanged(app, event)
            global nii_file_path
            nii_file_path = app.BraTSfolderTextArea.Value;
        end

        % Value changed function: matdatafolderTextArea_2
        function matdatafolderTextArea_2ValueChanged(app, event)
            global cpx_file_path
            cpx_file_path = app.matdatafolderTextArea_2.Value;
        end

        % Button pushed function: matopenButton_2
        function matopenButton_2Pushed(app, event)
            global cpx_file_path
            cpx_file_path = uigetdir('..');
            app.matdatafolderTextArea_2.Value = cpx_file_path;
        end

        % Value changed function: sliceintervalSpinner
        function sliceintervalSpinnerValueChanged(app, event)
            global cpx_slice_inter
            cpx_slice_inter = app.sliceintervalSpinner.Value;
        end

        % Value changed function: brainvoxelsizeSpinner
        function brainvoxelsizeSpinnerValueChanged(app, event)
            global cpx_brain_size
            cpx_brain_size = app.brainvoxelsizeSpinner.Value;
        end

        % Value changed function: lesionvoxelsizeSpinner
        function lesionvoxelsizeSpinnerValueChanged(app, event)
            global cpx_lesion_size
            cpx_lesion_size = app.lesionvoxelsizeSpinner.Value;
        end

        % Button pushed function: Step2RunButton
        function Step2RunButtonPushed(app, event)
            clc
            %% prepare wait bar
            text = "connceting to workers...";
            app.clickRuntostartLabel_2.Text = text;
            hold(app.step2_waitbar, 'on')
            plot(app.step2_waitbar,[0,1],[0,0],'-','LineWidth',15,'Color',[0.9,0.9,0.9]);
            hold(app.step2_waitbar,'off')
            drawnow
%             parpool();
            global nii_file_path mat_file_path cpx_file_path cpx_patient_num cpx_lesion_size cpx_brain_size cpx_slice_inter
            %% preset params
            brats_folder = char(nii_file_path); % enter BraTS file folder dir
            % you can download BraTS data at http://braintumorsegmentation.org/
            fastmri_folder = char(mat_file_path); % enter fastMRI file folder dir for phase map
            cpx_folder = char(cpx_file_path); % enter complex images file save dir
            [folder_list, total_patient_num] = get_sub_folder(brats_folder);
            fprintf('load .nii file from %s that has %s patients\n', [brats_folder, string(total_patient_num)])
            patient_num = cpx_patient_num; % set the number of patient to be converted
            patient_num = min(patient_num, total_patient_num);
            lesion_size = cpx_lesion_size; % minimum lesion size
            brain_size = cpx_brain_size; % minimum brain size
            slice_inter = cpx_slice_inter; % interval to select frames
            %% These data may cause problems
            QC_flag = 1; % wether to skip 
            QC_list = xlsread("BraTS2020_QC.xlsx","Sheet1","A:A")';
            count = 1;
            while count<patient_num+1 && QC_flag
                patient_id = split(folder_list(count),filesep);
                patient_id = patient_id(end);
                patient_no = split(patient_id,"_");
                patient_no = patient_no(end);
                if ismember(str2double(patient_no), QC_list) && QC_flag
                    fprintf('{> patient %s has certain problem and skipped\n', patient_id);
                    folder_list(count) = [];
                    continue
                end
                count = count+1;
            end
            %% prepare wait bar
            tstart = tic;
            text = "prcessing data: 0/"+num2str(patient_num)+" est. time: 00:00";
            app.clickRuntostartLabel_2.Text = text;
            drawnow
            %% notice that BraTS2020's data contains 2019 & 2018, see name_mapping.csv in BraTS2020 folder for more details
            for patient = 1:patient_num % 152 is a good sample, 133
                patient_loc = char(folder_list(patient));
                patient_id = split(folder_list(patient),filesep);
                patient_id = patient_id(end);
                % read nii from patient folder
                [T1_org, T1ce_org, T2_org, Flair_org, Tumor_mask_org] = read_nii(patient_loc);
                if patient == 344; Tumor_mask_org(105:107,1:2,57:59) = 0; end  % 344's mask has a fault 
                %% area of lesion/tumor_core/enhanced/necrotic/brain, choose slice according to settings
                Tumor_mask_org = loc_brain(T2_org, Tumor_mask_org);
                Mask_area = cal_mask_area(Tumor_mask_org);
                Feasible_slice = find(Mask_area(1,:)>lesion_size & Mask_area(5,:)>brain_size);
                Feasible_slice = Feasible_slice(1:slice_inter:end);
                if isempty(Feasible_slice)
                    fprintf('{> patient %s does not have enough slice\n', patient_id);
                    continue
                else
                    fprintf('{> patient %s processing with slice num %s\n', [patient_id, length(Feasible_slice)]);
                end
                T1_org = T1_org(:,:,Feasible_slice);
                T1ce_org = T1ce_org(:,:,Feasible_slice);
                T2_org = T2_org(:,:,Feasible_slice);
                Flair_org = Flair_org(:,:,Feasible_slice);
                Tumor_mask_org = Tumor_mask_org(:,:,Feasible_slice);
                Mask_area = cal_mask_area(Tumor_mask_org);
                show_slice = find(Mask_area(1,:) == max(Mask_area(1,:)), 1); % choose one slice with largest lesion to show
                %% create skin & skull with nature texture & record gap:6 skull:7 in mask
                fprintf('   create skull => ')
                [T1_sk, T1ce_sk, T2_sk, Flair_sk, Tumor_mask] = create_skull(T1_org, T1ce_org, T2_org, Flair_org, Tumor_mask_org);
                %% add phase to get complex valued image
                fprintf('simulate phase => ')
                [T1, T1ce, T2, Flair] = real_to_complex(T1_sk, T1ce_sk, T2_sk, Flair_sk, Tumor_mask, fastmri_folder);
                %% save complex data to .mat
                if ~exist(fullfile(cpx_folder,patient_id),'dir')
                    mkdir(fullfile(cpx_folder,patient_id))
                end
                save(fullfile(cpx_folder,patient_id,patient_id+"_t1.mat"),"T1")
                save(fullfile(cpx_folder,patient_id,patient_id+"_t1ce.mat"),"T1ce")
                save(fullfile(cpx_folder,patient_id,patient_id+"_t2.mat"),"T2")
                save(fullfile(cpx_folder,patient_id,patient_id+"_flair.mat"),"Flair")
                save(fullfile(cpx_folder,patient_id,patient_id+"_seg.mat"),"Tumor_mask")
                fprintf('done\n')
                 %% plot waitbar
                hold(app.step2_waitbar, 'on')
                plot(app.step2_waitbar,[0,patient/patient_num],[0,0],'-','LineWidth',15,'Color',[0.1,0.9,0.1]);            
                hold(app.step2_waitbar,'off')
                tnow = toc(tstart);
                term = tnow/patient*(patient_num-patient);
                term_min = floor(term/60);
                term_sec = floor(mod(term,60));
                text = "prcessing data: "+num2str(patient)+"/"+num2str(patient_num)+" est. time: "+num2str(term_min,'%02d')+":"+num2str(term_sec,'%02d');
                app.clickRuntostartLabel_2.Text = text;
                drawnow
            end
%             delete(gcp);
        end

        % Value changed function: imagesizeSpinner
        function imagesizeSpinnerValueChanged(app, event)
            global fastmri_size
            fastmri_size = app.imagesizeSpinner.Value;
        end

        % Value changed function: patientnumSpinner
        function patientnumSpinnerValueChanged(app, event)
            global cpx_patient_num
            cpx_patient_num = app.patientnumSpinner.Value;    
        end

        % Button pushed function: CESTopenButton
        function CESTopenButtonPushed(app, event)
            global CEST_file_path
            CEST_file_path = uigetdir('..');
            app.CESTsavefolderTextArea.Value = CEST_file_path;
        end

        % Value changed function: CESTsavefolderTextArea
        function CESTsavefolderTextAreaValueChanged(app, event)
            global CEST_file_path
            CEST_file_path = app.CESTsavefolderTextArea.Value;
        end

        % Value changed function: patientnumSpinner_2
        function patientnumSpinner_2ValueChanged(app, event)
            global CEST_patient_num
            CEST_patient_num = app.patientnumSpinner_2.Value;
        end

        % Value changed function: imagesizeSpinner_2
        function imagesizeSpinner_2ValueChanged(app, event)
            global CEST_size
            CEST_size = app.imagesizeSpinner_2.Value;
        end

        % Value changed function: dataenhanceSwitch
        function dataenhanceSwitchValueChanged(app, event)
            global CEST_enhance
            value = app.dataenhanceSwitch.Value;
            if value == "On"
                CEST_enhance = 1;
            else
                CEST_enhance = 0;
            end
        end

        % Value changed function: TSET2blurSwitch
        function TSET2blurSwitchValueChanged(app, event)
            global CEST_blur
            value = app.TSET2blurSwitch.Value;
            if value == "On"
                CEST_blur = 1;
            else
                CEST_blur = 0;
            end  
        end

        % Button pushed function: Step3RunButton
        function Step3RunButtonPushed(app, event)
            clc
            %% prepare wait bar
            text = "connceting to workers...";
            app.clickRuntostartLabel_3.Text = text;
            hold(app.step3_waitbar, 'on')
            plot(app.step3_waitbar,[0,1],[0,0],'-','LineWidth',15,'Color',[0.9,0.9,0.9]);
            hold(app.step3_waitbar,'off')
            drawnow
%             parpool();
            global mat_file_path cpx_file_path CEST_file_path CEST_patient_num CEST_size CEST_enhance CEST_blur CEST_fullcsm
            %% preset params
            cpx_folder=char(cpx_file_path); % enter complex images file save dir
            [cpx_folder_list, total_patient_num] = get_sub_folder(cpx_folder);
            fprintf('load complex images from %s that has %s patients\n', [cpx_folder, string(total_patient_num)])
            CEST_folder=char(CEST_file_path); % enter CEST images file save dir
            full_csm = CEST_fullcsm; % choose "full" or "partial" for region of csm
            fastmri_folder = char(mat_file_path); % enter fastMRI file folder dir for csm
            [fastmri_folder_list,~] = get_sub_folder(fastmri_folder);
            CEST_imgsize = [CEST_size, CEST_size]; % size for generated CEST img
            patient_num = CEST_patient_num; % set the number of patient to be converted
            patient_num = min(patient_num, total_patient_num);
            slice_inter = 1; % interval to select frames
            enhance_flag = CEST_enhance; % choose 1 or 0 for rigid body transform enhancement
            shift_distance = 20; % max translation distance from the edge
            T2blur_flag = CEST_blur; % choose 1 or 0 for TSE T2 blur
            motion_flag = 0; % choose 1 or 0 for motion artifact
            %% read scan freq
            scanned_offset = transpose(load(fullfile('BM_simu_Zspetrum','Offset frequencies for APT scans - 54pts.txt')));
            scanned_offset = round(scanned_offset/1.2314)/100;
            scanned_offset_sorted = sort(scanned_offset,'descend');
            [scanned_offset_uniq, col] = unique(scanned_offset_sorted, "stable");
            %% These data may cause problems
            QC_flag = 1; % wether to skip 
            QC_list = xlsread("BraTS2020_QC.xlsx","Sheet1","A:A")';
            count = 1;
            while count<patient_num+1 && QC_flag
                patient_id = split(cpx_folder_list(count),filesep);
                patient_id = patient_id(end);
                patient_no = split(patient_id,"_");
                patient_no = patient_no(end);
                if ismember(str2double(patient_no), QC_list) && QC_flag
                    fprintf('{> patient %s has certain problem and skipped\n', patient_id);
                    cpx_folder_list(count) = [];
                    continue
                end
                count = count+1;
            end
            %% prepare wait bar
            tstart = tic;
            text = "prcessing data: 0/"+num2str(patient_num)+" est. time: 00:00";
            app.clickRuntostartLabel_3.Text = text;
            drawnow
            %% read patient data
            for patient= 1:patient_num % 152 is a good sample
                patient_loc = cpx_folder_list(patient);
                patient_id = split(cpx_folder_list(patient),filesep);
                patient_id = patient_id(end);
                %% load complex valuled data
                load(fullfile(patient_loc, patient_id+"_t1.mat"));
                load(fullfile(patient_loc, patient_id+"_t1ce.mat"));
                load(fullfile(patient_loc, patient_id+"_t2.mat")); 
                load(fullfile(patient_loc, patient_id+"_flair.mat"));
                load(fullfile(patient_loc, patient_id+"_seg.mat"));
                fprintf('{> patient %s converting to CEST img', patient_id);
                %% select slices at intervals since the adjacent slices are similar
                Feasible_slice=1:slice_inter:size(Tumor_mask,3);
                fprintf('with slice num %s\n', string(length(Feasible_slice)));
                T1 = T1(:,:,Feasible_slice);
                T1ce = T1ce(:,:,Feasible_slice);
                T2 = T2(:,:,Feasible_slice);
                Flair = Flair(:,:,Feasible_slice);
                Tumor_mask = Tumor_mask(:,:,Feasible_slice);
                Tumor_area = cal_mask_area(Tumor_mask);
                show_slice = find(Tumor_area(1,:)==max(Tumor_area(1,:)), 1);
                %% divide image into normal/tumor/edema/necrotic/skull/skin & calculate T1/T2 grade maps through discretization
                Division = zone_division(Tumor_mask);
                tissue = ["normal", "tumor", "edema", "necro", "skull", "skin"];
                t_level = [9, 9, 9, 4, 4, 4]; % num of relaxation time grades 
                t1_lb = [0.25, 0.1, 0.1, 0.05, 0.1, 0.1]; % lower bound of T1
                t1_ub = [0.9, 0.9, 0.95, 0.25, 0.95, 0.9]; % upper bound of T1
                t2_lb = [0.1, 0.1, 0.1, 0.8, 0.1, 0.1]; % lower bound of T2
                t2_ub = [0.8, 0.8, 0.9, 0.95, 0.95, 0.5]; % upper bound of T2
                t_edge = ["edge", "noedge", "noedge", "noedge", "edge", "edge"]; % whether to excluded edges
                T1_divzone = T1.*Division;
                T2_divzone = T2.*Division;
                T1_dismap = zeros(size(T1_divzone));
                T2_dismap = zeros(size(T2_divzone));
                fprintf('   T1/T2 mapping => ')
                for zone=1:length(tissue)
                    for s=1:size(T1_divzone,3)
                        % T1 discretization for estimated T1 mapping, lower amp correspondences to higher T1
                        T1_zone = abs(squeeze(T1_divzone(:,:,s,zone)));
                        T1_roi = squeeze(Division(:,:,s,zone));
                        T1_dis_config.levels  = t_level(zone);
                        T1_dis_config.lb      = t1_lb(zone);
                        T1_dis_config.ub      = t1_ub(zone);
                        T1_dis_config.sort    = "descend";
                        T1_dis_config.edge    = t_edge(zone);
                        T1_dismap(:,:,s,zone) = amp_discretization(T1_zone, T1_roi, T1_dis_config);
                        % T2 discretization for estimated T2 mapping, higher amp correspondences to higher T2
                        T2_zone = abs(squeeze(T2_divzone(:,:,s,zone)));
                        T2_roi = squeeze(Division(:,:,s,zone));
                        T2_dis_config.levels  = t_level(zone);
                        T2_dis_config.lb      = t2_lb(zone);
                        T2_dis_config.ub      = t2_ub(zone);
                        T2_dis_config.sort    = "ascend";
                        T2_dis_config.edge    = t_edge(zone);
                        T2_dismap(:,:,s,zone) = amp_discretization(T2_zone, T2_roi, T2_dis_config);
                    end
                end
                %% generate Z map for each region according to T1/T2 grade maps
                fprintf('generate Z map => ')
                Z_image_brain = zeros([size(T1),length(scanned_offset)]);
                Z_image_bg = zeros([size(T1),length(scanned_offset)]);
                parfor s=1:size(T1_divzone,3)
                    Z_image_brain_slice = zeros([size(T1,1),size(T1,2),length(scanned_offset),length(tissue)]);
                    for zone=1:length(tissue)
                       T1_zone = squeeze(T1_dismap(:,:,s,zone));
                       T2_zone = squeeze(T2_dismap(:,:,s,zone));
                       roi = squeeze(Division(:,:,s,zone));
                       Z_image_brain_slice(:,:,:,zone) = zsp_map(T1_zone, T2_zone, roi, t_level(zone), tissue(zone));
                    end
                    Z_image_brain_slice = sum(Z_image_brain_slice,4);
                    Z_image_brain(:,:,s,:) = Z_image_brain_slice;
                    T1_bg = abs(squeeze(T1_divzone(:,:,s,7)));
                    T2_bg = abs(squeeze(T2_divzone(:,:,s,7)));
                    roi_bg = squeeze(Division(:,:,s,7));
                    Z_image_bg(:,:,s,:) = zsp_map_bg(T1_bg, T2_bg, roi_bg, Z_image_brain_slice);
                end
                % smooth junction between different tissues
                Z_image = Z_image_brain + Z_image_bg;
                Z_image = zimage_smooth(Z_image, Division, 2);
                %% get CEST image for T1/T2/Flair
                fprintf('synthesize CEST image => ')
                CEST_image_T1 = T1.*Z_image;
                CEST_image_T2 = T2.*Z_image;
                CEST_image_Flair = Flair.*Z_image;
                %% randomly crop CEST img for enhancement at each slice
                if enhance_flag
                    fprintf('rigid trans enhancement => ')
                    brain_roi = sum(Division(:,:,:,1:length(tissue)),4);
                    CEST_image_T1 = random_crop(CEST_image_T1, brain_roi, shift_distance);
                    CEST_image_T2 = random_crop(CEST_image_T2, brain_roi, shift_distance);
                    CEST_image_Flair = random_crop(CEST_image_Flair, brain_roi, shift_distance);
                end
                %% resize CEST img to preset size
                CEST_image_T1 = imresize(CEST_image_T1, CEST_imgsize, 'nearest');
                CEST_image_T2 = imresize(CEST_image_T2, CEST_imgsize, 'nearest');
                CEST_image_Flair = imresize(CEST_image_Flair, CEST_imgsize, 'nearest');
                %% add extra noise, T2 blur or motion effect
                fprintf('add noise => ')
                noise_level = 0.02*(1+(rand()-0.5)*0.5);
                T2decay_filter = TSE_pss(0.25*(1+(rand()-0.5)*0.2), CEST_imgsize);
                for s=1:size(T1_divzone,3)
                    CEST_image_T1(:,:,s,:) = ksp_add_noise(squeeze(CEST_image_T1(:,:,s,:)), noise_level);
                    CEST_image_T2(:,:,s,:) = ksp_add_noise(squeeze(CEST_image_T2(:,:,s,:)), noise_level);
                    CEST_image_Flair(:,:,s,:) = ksp_add_noise(squeeze(CEST_image_Flair(:,:,s,:)), noise_level);
                    if motion_flag
                        % add alias and extra noise around 0ppm
                        alias_level = 0.1*(1+(rand()-0.5)*0.5);
                        CEST_image_T1(:,:,s,:) = ksp_add_alias(squeeze(CEST_image_T1(:,:,s,:)), alias_level);
                        CEST_image_T2(:,:,s,:) = ksp_add_alias(squeeze(CEST_image_T2(:,:,s,:)), alias_level);
                        CEST_image_Flair(:,:,s,:) = ksp_add_alias(squeeze(CEST_image_Flair(:,:,s,:)), alias_level);
                    elseif T2blur_flag
                        % add T2 decay filtering caused by TSE
                        CEST_image_T1(:,:,s,:) = T2_blur(squeeze(CEST_image_T1(:,:,s,:)), T2decay_filter);
                        CEST_image_T2(:,:,s,:) = T2_blur(squeeze(CEST_image_T2(:,:,s,:)), T2decay_filter);
                        CEST_image_Flair(:,:,s,:) = T2_blur(squeeze(CEST_image_Flair(:,:,s,:)), T2decay_filter);
                    end
                end
                %% assign CSM from fastMRI dataset
                fprintf('assign csm => ')
                save_id = split(patient_id,"_");
                save_id = save_id(end);
                T1_dir = fullfile(CEST_folder, "T1_"+full_csm, save_id);
                T2_dir = fullfile(CEST_folder, "T2_"+full_csm, save_id);
                Flair_dir = fullfile(CEST_folder, "FLAIR_"+full_csm, save_id);
                if ~exist(T1_dir,'dir'); mkdir(T1_dir); mkdir(T2_dir); mkdir(Flair_dir); end
                for s=1:size(T1_divzone,3)
                    csm_org = load_fastmri_csm(CEST_imgsize, fastmri_folder_list);
                    [csm_T1, ref_T1] = assign_csm(squeeze(CEST_image_T1(:,:,s,1)), csm_org, full_csm);
                    [csm_T2, ref_T2] = assign_csm(squeeze(CEST_image_T2(:,:,s,1)), csm_org, full_csm);
                    [csm_Flair, ref_Flair] = assign_csm(squeeze(CEST_image_Flair(:,:,s,1)), csm_org, full_csm);
                %% save simulated CEST image & CSM
                    APT_data = squeeze(CEST_image_T1(:,:,s,:)); save(fullfile(T1_dir, "CEST"+num2str(s,"%02d")+".mat"), "APT_data");
                    APT_data = squeeze(CEST_image_T2(:,:,s,:)); save(fullfile(T2_dir, "CEST"+num2str(s,"%02d")+".mat"), "APT_data");
                    APT_data = squeeze(CEST_image_Flair(:,:,s,:)); save(fullfile(Flair_dir, "CEST"+num2str(s,"%02d")+".mat"), "APT_data");
                    sensitivities = csm_T1; reference = ref_T1;
                    save(fullfile(T1_dir, "espirit"+num2str(s,"%02d")+".mat"), "sensitivities", "reference");
                    sensitivities = csm_T2; reference = ref_T2;
                    save(fullfile(T2_dir, "espirit"+num2str(s,"%02d")+".mat"), "sensitivities", "reference");
                    sensitivities = csm_Flair; reference = ref_Flair;
                    save(fullfile(Flair_dir, "espirit"+num2str(s,"%02d")+".mat"), "sensitivities", "reference");
                end
                fprintf('done\n')
                 %% plot waitbar
                hold(app.step3_waitbar, 'on')
                plot(app.step3_waitbar,[0,patient/patient_num],[0,0],'-','LineWidth',15,'Color',[0.1,0.9,0.1]);            
                hold(app.step3_waitbar,'off')
                tnow = toc(tstart);
                term = tnow/patient*(patient_num-patient);
                term_min = floor(term/60);
                term_sec = floor(mod(term,60));
                text = "prcessing data: "+num2str(patient)+"/"+num2str(patient_num)+" est. time: "+num2str(term_min,'%02d')+":"+num2str(term_sec,'%02d');
                app.clickRuntostartLabel_3.Text = text;
                drawnow
            end
%             delete(gcp);
        end

        % Value changed function: datanumSpinner
        function datanumSpinnerValueChanged(app, event)
            global fastmri_datanum
            fastmri_datanum = app.datanumSpinner.Value;
        end

        % Button pushed function: ViewRunButton
        function ViewRunButtonPushed(app, event)
            clc
            global view_mod view_patient_num CEST_fullcsm CEST_file_path cpx_file_path
            %% preset params
            patient_no = view_patient_num; % which patient to show 
            full_csm = CEST_fullcsm; % full csm or partial 
            CEST_folder= char(CEST_file_path); % enter CEST images file save dir
            cpx_folder = char(cpx_file_path); % enter complex images file save dir
            modality = view_mod; % which modality to show T1/T2/Flair
            slice_inter = 1;
            file_folder=fullfile(CEST_folder, char(modality+"_"+full_csm));
            [file_folder_list, CEST_num] = get_sub_folder(file_folder);
            patient_no = min(patient_no, CEST_num);
            fprintf('quality control for CEST images in %s that has %s patients\n', [CEST_folder, string(CEST_num)])
            [cpx_folder_list, ~] = get_sub_folder(cpx_folder);
            %% read scan freq
            scanned_offset = transpose(load(fullfile('BM_simu_Zspetrum','Offset frequencies for APT scans - 54pts.txt')));
            scanned_offset = round(scanned_offset/1.2314)/100;
            scanned_offset_sorted = sort(scanned_offset,'descend');
            [scanned_offset_uniq, col] = unique(scanned_offset_sorted, "stable");
            %% read patient data
            patient_num = split(file_folder_list(patient_no),filesep); patient_num = str2double(patient_num(end));
            patient_id = split(cpx_folder_list(patient_no),filesep); patient_id = patient_id(end);
            %% locate the best view slice
            load(fullfile(cpx_folder_list(patient_no), patient_id+"_seg.mat"));
            Tumor_area = cal_mask_area(Tumor_mask);
            show_slice = find(Tumor_area(1,:)==max(Tumor_area(1,:)), 1);
            CEST_slice = 1:slice_inter:size(Tumor_mask,3);
            show_slice_loc = abs(CEST_slice/show_slice-1);
            show_CEST_slice = find(show_slice_loc==min(show_slice_loc));
            %% load data
            load(fullfile(file_folder_list(patient_no), "CEST"+num2str(show_CEST_slice,"%02d")+".mat")); CEST_image = APT_data;
            load(fullfile(file_folder_list(patient_no), "espirit"+num2str(show_CEST_slice,"%02d")+".mat")); CSM = sensitivities;
            fprintf('{> patient %s slice %s simulated CEST image with %s\n', [patient_id, string(show_CEST_slice), modality]);
            %% show simulated CEST image for T1/T2/Flair
            color_mask=imresize(cmp_brats(squeeze(Tumor_mask(:,:,CEST_slice(show_CEST_slice)))), [size(CEST_image,1), size(CEST_image,2)]);
            imshow(color_mask, 'Parent', app.UIAxes_4)
            slice_1=find(scanned_offset_sorted==inf, 1);
            imshow(abs(squeeze(CEST_image(:,:,slice_1))),[0,1], 'Parent', app.UIAxes)
            colormap(app.UIAxes,"jet");
            slice_2=find(scanned_offset_sorted==3.5, 1);
            imshow(abs(squeeze(CEST_image(:,:,slice_2))),[0,1], 'Parent', app.UIAxes_2)
            colormap(app.UIAxes_2,"jet");
            slice_3=find(scanned_offset_sorted==0.0, 1);
            imshow(abs(squeeze(CEST_image(:,:,slice_3))),[0,1], 'Parent', app.UIAxes_3)
            colormap(app.UIAxes_3,"jet");
            slice_4=find(scanned_offset_sorted==-3.5, 1, 'last');
            apt_neg=squeeze(CEST_image(:,:,slice_4))./squeeze(CEST_image(:,:,slice_1));
            apt_pos=squeeze(CEST_image(:,:,slice_2))./squeeze(CEST_image(:,:,slice_1));
            apt_roi=imclose(imbinarize(abs(squeeze(CEST_image(:,:,slice_1))),0.2),strel('disk',20));
            rainbow_map=rainbow('rainbow_idl');
            imshow((abs(apt_neg)-abs(apt_pos)).*apt_roi-(1-apt_roi),[-0.05,0.05], 'Parent', app.UIAxes_5)
            colormap(app.UIAxes_5,rainbow_map);
            imshow(abs(squeeze(CSM(:,:,1))),[], 'Parent', app.UIAxes_6);
        end

        % Value changed function: patientidSpinner
        function patientidSpinnerValueChanged(app, event)
            global view_patient_num
            view_patient_num = app.patientidSpinner.Value;
        end

        % Selection changed function: readoutmodalityButtonGroup
        function readoutmodalityButtonGroupSelectionChanged(app, event)
            global view_mod
            selectedButton = app.readoutmodalityButtonGroup.SelectedObject;
            view_mod = selectedButton.Text;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BraTSCESTUIFigure = uifigure;
            app.BraTSCESTUIFigure.Name = 'BraTS-CEST';
            app.TabGroup = uitabgroup(app.BraTSCESTUIFigure);
            app.startTab = uitab(app.TabGroup);
            app.startTab.Title = 'Start';
            app.fastMRI = uilabel(app.startTab);
            app.fastMRI.Text = 'fastMRI';
            app.fastMRI_web = uilabel(app.startTab);
            app.fastMRI_web.Text = 'https://fastmri.med.nyu.edu/';
            app.fastMRI_button = uibutton(app.startTab, 'push');
            app.fastMRI_button.ButtonPushedFcn = createCallbackFcn(app, @fastMRI_buttonPushed, true);
            app.fastMRI_button.Text = 'browse';
            app.BraTS = uilabel(app.startTab);
            app.BraTS.Text = 'BraTS';
            app.BraTS_web = uilabel(app.startTab);
            app.BraTS_web.Text = 'http://braintumorsegmentation.org/';
            app.BraTS_button = uibutton(app.startTab, 'push');
            app.BraTS_button.ButtonPushedFcn = createCallbackFcn(app, @BraTS_buttonButtonPushed, true);
            app.BraTS_button.Text = 'browse';
            app.BeforestartuppleasedownloadthefollowingdatasetsLabel = uilabel(app.startTab);
            app.BeforestartuppleasedownloadthefollowingdatasetsLabel.Text = 'Before start up, please download the following datasets:';
            app.author_name = uilabel(app.startTab);
            app.author_name.Text = 'designed by WangYuyan';
            app.welcome = uilabel(app.startTab);
            app.welcome.Text = 'Welcome to use BraTS-CEST';
            app.welcome_1 = uilabel(app.startTab);
            app.welcome_1.Text = 'we offer you a realistic CEST tumor image dataset';
            app.TipsclickonthetoptabstoswitchdifferentstepsLabel = uilabel(app.startTab);
            app.TipsclickonthetoptabstoswitchdifferentstepsLabel.Text = 'Tips: click on the top tabs to switch different steps';
            app.step1_page = uitab(app.TabGroup);
            app.step1_page.Title = 'Step1';
            app.step1_waitbar = uiaxes(app.step1_page);
            app.PreprocessoffastMRIdatasettomatfileLabel = uilabel(app.step1_page);
            app.PreprocessoffastMRIdatasettomatfileLabel.Text = 'Pre-process of fastMRI dataset to .mat file';
            app.fastMRIfolderTextAreaLabel = uilabel(app.step1_page);
            app.fastMRIfolderTextAreaLabel.Text = 'fastMRI folder';
            app.fastMRIfolderTextArea = uitextarea(app.step1_page);
            app.fastMRIfolderTextArea.ValueChangedFcn = createCallbackFcn(app, @fastMRIfolderTextAreaValueChanged, true);
            app.fastMRIfolderTextArea.Value = {'./demo_fastMRI_h5file'};
            app.h5openButton = uibutton(app.step1_page, 'push');
            app.h5openButton.ButtonPushedFcn = createCallbackFcn(app, @h5openButtonPushed, true);
            app.h5openButton.Text = 'choose';
            app.matopenButton = uibutton(app.step1_page, 'push');
            app.matopenButton.ButtonPushedFcn = createCallbackFcn(app, @matopenButtonPushed, true);
            app.matopenButton.Text = 'choose';
            app.matsavefolderLabel = uilabel(app.step1_page);
            app.matsavefolderLabel.Text = 'mat save folder';
            app.matdatafolderTextArea = uitextarea(app.step1_page);
            app.matdatafolderTextArea.ValueChangedFcn = createCallbackFcn(app, @matdatafolderTextAreaValueChanged, true);
            app.matdatafolderTextArea.Value = {'./Data/fastMRI'};
            app.TipsrawdatathatdoesnotmeettheminimumconditionwillbeskippedLabel = uilabel(app.step1_page);
            app.TipsrawdatathatdoesnotmeettheminimumconditionwillbeskippedLabel.Text = 'Tips. rawdata that does not meet the minimum condition will be skipped';
            app.numofslicesSpinnerLabel = uilabel(app.step1_page);
            app.numofslicesSpinnerLabel.Text = 'num of slices';
            app.numofslicesSpinner = uispinner(app.step1_page);
            app.numofslicesSpinner.Limits = [1 50];
            app.numofslicesSpinner.ValueChangedFcn = createCallbackFcn(app, @numofslicesSpinnerValueChanged, true);
            app.numofslicesSpinner.Value = 10;
            app.numofcoilsSpinnerLabel = uilabel(app.step1_page);
            app.numofcoilsSpinnerLabel.Text = 'num of coils';
            app.numofcoilsSpinner = uispinner(app.step1_page);
            app.numofcoilsSpinner.Limits = [4 24];
            app.numofcoilsSpinner.ValueChangedFcn = createCallbackFcn(app, @numofcoilsSpinnerValueChanged, true);
            app.numofcoilsSpinner.Value = 16;
            app.PresettingsLabel = uilabel(app.step1_page);
            app.PresettingsLabel.Text = 'Presettings:';
            app.Step1RunButton = uibutton(app.step1_page, 'push');
            app.Step1RunButton.ButtonPushedFcn = createCallbackFcn(app, @Step1RunButtonPushed, true);
            app.Step1RunButton.Text = 'Run';
            app.clickRuntostartLabel = uilabel(app.step1_page);
            app.clickRuntostartLabel.Text = 'click Run to start';
            app.imagesizeSpinnerLabel = uilabel(app.step1_page);
            app.imagesizeSpinnerLabel.Text = 'image size';
            app.imagesizeSpinner = uispinner(app.step1_page);
            app.imagesizeSpinner.Limits = [48 Inf];
            app.imagesizeSpinner.ValueChangedFcn = createCallbackFcn(app, @imagesizeSpinnerValueChanged, true);
            app.imagesizeSpinner.Value = 96;
            app.datanumSpinnerLabel = uilabel(app.step1_page);
            app.datanumSpinnerLabel.Text = 'data num';
            app.datanumSpinner = uispinner(app.step1_page);
            app.datanumSpinner.Step = 10;
            app.datanumSpinner.Limits = [1 Inf];
            app.datanumSpinner.ValueChangedFcn = createCallbackFcn(app, @datanumSpinnerValueChanged, true);
            app.datanumSpinner.Value = 10;
            app.step2_page = uitab(app.TabGroup);
            app.step2_page.Title = 'Step2';
            app.step2_waitbar = uiaxes(app.step2_page);
            app.TransferBraTSfromrealtocomplexLabel = uilabel(app.step2_page);
            app.TransferBraTSfromrealtocomplexLabel.Text = 'Transfer BraTS from real to complex';
            app.niiopenButton = uibutton(app.step2_page, 'push');
            app.niiopenButton.ButtonPushedFcn = createCallbackFcn(app, @niiopenButtonPushed, true);
            app.niiopenButton.Text = 'choose';
            app.matopenButton_2 = uibutton(app.step2_page, 'push');
            app.matopenButton_2.ButtonPushedFcn = createCallbackFcn(app, @matopenButton_2Pushed, true);
            app.matopenButton_2.Text = 'choose';
            app.TipsrawdatathatarerecordedasdefectivewillbeskippedLabel = uilabel(app.step2_page);
            app.TipsrawdatathatarerecordedasdefectivewillbeskippedLabel.Text = 'Tips. rawdata that are recorded as defective will be skipped';
            app.PresettingsLabel_2 = uilabel(app.step2_page);
            app.PresettingsLabel_2.Text = 'Presettings:';
            app.Step2RunButton = uibutton(app.step2_page, 'push');
            app.Step2RunButton.ButtonPushedFcn = createCallbackFcn(app, @Step2RunButtonPushed, true);
            app.Step2RunButton.Text = 'Run';
            app.clickRuntostartLabel_2 = uilabel(app.step2_page);
            app.clickRuntostartLabel_2.Text = 'click Run to start';
            app.BraTSfolderTextAreaLabel = uilabel(app.step2_page);
            app.BraTSfolderTextAreaLabel.Text = 'BraTS folder';
            app.BraTSfolderTextArea = uitextarea(app.step2_page);
            app.BraTSfolderTextArea.ValueChangedFcn = createCallbackFcn(app, @BraTSfolderTextAreaValueChanged, true);
            app.BraTSfolderTextArea.Value = {'./demo_BraTS2020_niifile'};
            app.complexfolderLabel = uilabel(app.step2_page);
            app.complexfolderLabel.Text = 'complex folder';
            app.matdatafolderTextArea_2 = uitextarea(app.step2_page);
            app.matdatafolderTextArea_2.ValueChangedFcn = createCallbackFcn(app, @matdatafolderTextArea_2ValueChanged, true);
            app.matdatafolderTextArea_2.Value = {'./Data/BraTS_complex'};
            app.sliceintervalSpinnerLabel = uilabel(app.step2_page);
            app.sliceintervalSpinnerLabel.Text = 'slice interval';
            app.sliceintervalSpinner = uispinner(app.step2_page);
            app.sliceintervalSpinner.Limits = [1 10];
            app.sliceintervalSpinner.ValueChangedFcn = createCallbackFcn(app, @sliceintervalSpinnerValueChanged, true);
            app.sliceintervalSpinner.Value = 5;
            app.brainvoxelsizeSpinnerLabel = uilabel(app.step2_page);
            app.brainvoxelsizeSpinnerLabel.Text = 'brain voxel size';
            app.brainvoxelsizeSpinner = uispinner(app.step2_page);
            app.brainvoxelsizeSpinner.Step = 100;
            app.brainvoxelsizeSpinner.Limits = [400 14400];
            app.brainvoxelsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @brainvoxelsizeSpinnerValueChanged, true);
            app.brainvoxelsizeSpinner.Value = 1000;
            app.lesionvoxelsizeSpinnerLabel = uilabel(app.step2_page);
            app.lesionvoxelsizeSpinnerLabel.Text = 'lesion voxel size';
            app.lesionvoxelsizeSpinner = uispinner(app.step2_page);
            app.lesionvoxelsizeSpinner.Step = 10;
            app.lesionvoxelsizeSpinner.Limits = [100 14400];
            app.lesionvoxelsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @lesionvoxelsizeSpinnerValueChanged, true);
            app.lesionvoxelsizeSpinner.Value = 144;
            app.patientnumSpinnerLabel = uilabel(app.step2_page);
            app.patientnumSpinnerLabel.Text = 'patient num';
            app.patientnumSpinner = uispinner(app.step2_page);
            app.patientnumSpinner.Limits = [1 Inf];
            app.patientnumSpinner.ValueChangedFcn = createCallbackFcn(app, @patientnumSpinnerValueChanged, true);
            app.patientnumSpinner.Value = 50;
            app.Step3Tab = uitab(app.TabGroup);
            app.Step3Tab.Title = 'Step3';
            app.step3_waitbar = uiaxes(app.Step3Tab);
            app.regionofCSMButtonGroup = uibuttongroup(app.Step3Tab);
            app.regionofCSMButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @regionofCSMButtonGroupSelectionChanged, true);
            app.regionofCSMButtonGroup.Title = 'region of CSM';
            app.fullFOVButton = uiradiobutton(app.regionofCSMButtonGroup);
            app.fullFOVButton.Text = 'full FOV';
            app.fullFOVButton.Value = true;
            app.partialFOVButton = uiradiobutton(app.regionofCSMButtonGroup);
            app.partialFOVButton.Text = 'partial FOV';
            app.AddCESTcontrastformulticoilBraTSCESTdataLabel = uilabel(app.Step3Tab);
            app.AddCESTcontrastformulticoilBraTSCESTdataLabel.Text = 'Add CEST contrast for multi-coil BraTS-CEST data';
            app.CESTopenButton = uibutton(app.Step3Tab, 'push');
            app.CESTopenButton.ButtonPushedFcn = createCallbackFcn(app, @CESTopenButtonPushed, true);
            app.CESTopenButton.Text = 'choose';
            app.PresettingsLabel_3 = uilabel(app.Step3Tab);
            app.PresettingsLabel_3.Text = 'Presettings:';
            app.CESTsavefolderLabel = uilabel(app.Step3Tab);
            app.CESTsavefolderLabel.Text = 'CEST save folder';
            app.CESTsavefolderTextArea = uitextarea(app.Step3Tab);
            app.CESTsavefolderTextArea.ValueChangedFcn = createCallbackFcn(app, @CESTsavefolderTextAreaValueChanged, true);
            app.CESTsavefolderTextArea.Value = {'./demo_BraTS_CEST'};
            app.dataenhanceSwitchLabel = uilabel(app.Step3Tab);
            app.dataenhanceSwitchLabel.Text = 'data enhance';
            app.dataenhanceSwitch = uiswitch(app.Step3Tab, 'slider');
            app.dataenhanceSwitch.ValueChangedFcn = createCallbackFcn(app, @dataenhanceSwitchValueChanged, true);
            app.dataenhanceSwitch.Value = 'On';
            app.TSET2blurSwitchLabel = uilabel(app.Step3Tab);
            app.TSET2blurSwitchLabel.Text = 'TSE T2 blur';
            app.TSET2blurSwitch = uiswitch(app.Step3Tab, 'slider');
            app.TSET2blurSwitch.ValueChangedFcn = createCallbackFcn(app, @TSET2blurSwitchValueChanged, true);
            app.TSET2blurSwitch.Value = 'On';
            app.patientnumSpinner_2Label = uilabel(app.Step3Tab);
            app.patientnumSpinner_2Label.Text = 'patient num';
            app.patientnumSpinner_2 = uispinner(app.Step3Tab);
            app.patientnumSpinner_2.Limits = [1 Inf];
            app.patientnumSpinner_2.ValueChangedFcn = createCallbackFcn(app, @patientnumSpinner_2ValueChanged, true);
            app.patientnumSpinner_2.Value = 50;
            app.imagesizeSpinner_2Label = uilabel(app.Step3Tab);
            app.imagesizeSpinner_2Label.Text = 'image size';
            app.imagesizeSpinner_2 = uispinner(app.Step3Tab);
            app.imagesizeSpinner_2.Limits = [48 Inf];
            app.imagesizeSpinner_2.ValueChangedFcn = createCallbackFcn(app, @imagesizeSpinner_2ValueChanged, true);
            app.imagesizeSpinner_2.Value = 96;
            app.TipschoosefullFOVofCSMforbetterperformanceontrainingLabel = uilabel(app.Step3Tab);
            app.TipschoosefullFOVofCSMforbetterperformanceontrainingLabel.Text = 'Tips. choose full FOV of CSM for better performance on training';
            app.Step3RunButton = uibutton(app.Step3Tab, 'push');
            app.Step3RunButton.ButtonPushedFcn = createCallbackFcn(app, @Step3RunButtonPushed, true);
            app.Step3RunButton.Text = 'Run';
            app.clickRuntostartLabel_3 = uilabel(app.Step3Tab);
            app.clickRuntostartLabel_3.Text = 'click Run to start';
            app.ViewTab = uitab(app.TabGroup);
            app.ViewTab.Title = 'View';
            app.UIAxes = uiaxes(app.ViewTab);
            app.UIAxes_2 = uiaxes(app.ViewTab);
            app.UIAxes_3 = uiaxes(app.ViewTab);
            app.UIAxes_4 = uiaxes(app.ViewTab);
            app.UIAxes_5 = uiaxes(app.ViewTab);
            app.UIAxes_6 = uiaxes(app.ViewTab);
            app.ViewtheBraTSCESTresultsLabel = uilabel(app.ViewTab);
            app.ViewtheBraTSCESTresultsLabel.Text = 'View the BraTS-CEST results';
            app.readoutmodalityButtonGroup = uibuttongroup(app.ViewTab);
            app.readoutmodalityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @readoutmodalityButtonGroupSelectionChanged, true);
            app.readoutmodalityButtonGroup.Title = 'read-out modality';
            app.T1Button = uiradiobutton(app.readoutmodalityButtonGroup);
            app.T1Button.Text = 'T1';
            app.T1Button.Value = true;
            app.T2Button = uiradiobutton(app.readoutmodalityButtonGroup);
            app.T2Button.Text = 'T2';
            app.FLAIRButton = uiradiobutton(app.readoutmodalityButtonGroup);
            app.FLAIRButton.Text = 'FLAIR';
            app.patientidSpinnerLabel = uilabel(app.ViewTab);
            app.patientidSpinnerLabel.Text = 'patient id';
            app.patientidSpinner = uispinner(app.ViewTab);
            app.patientidSpinner.Limits = [1 Inf];
            app.patientidSpinner.ValueChangedFcn = createCallbackFcn(app, @patientidSpinnerValueChanged, true);
            app.patientidSpinner.Value = 1;
            app.ViewRunButton = uibutton(app.ViewTab, 'push');
            app.ViewRunButton.ButtonPushedFcn = createCallbackFcn(app, @ViewRunButtonPushed, true);
            app.ViewRunButton.Text = 'Load';
            app.S0Label = uilabel(app.ViewTab);
            app.S0Label.Text = 'S0';
            app.divisionLabel = uilabel(app.ViewTab);
            app.divisionLabel.Text = 'division';
            app.ppmLabel = uilabel(app.ViewTab);
            app.ppmLabel.Text = '3.5ppm';
            app.APTwLabel = uilabel(app.ViewTab);
            app.APTwLabel.Text = 'APTw';
            app.ppmLabel_2 = uilabel(app.ViewTab);
            app.ppmLabel_2.Text = '0ppm';
            app.CSMLabel = uilabel(app.ViewTab);
            app.CSMLabel.Text = 'CSM';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_BraTS_CEST

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BraTSCESTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @start_up_init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BraTSCESTUIFigure)
        end
    end
end